classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        DetailsTab                      matlab.ui.container.Tab
        BioSimLabel                     matlab.ui.control.Label
        NumberofPeopleEditFieldLabel    matlab.ui.control.Label
        NumberofPeopleEditField         matlab.ui.control.NumericEditField
        StartButton                     matlab.ui.control.Button
        InitialPressurekPaEditFieldLabel  matlab.ui.control.Label
        InitialPressurekPaEditField     matlab.ui.control.NumericEditField
        AirTemperatureCEditFieldLabel   matlab.ui.control.Label
        AirTemperatureCEditField        matlab.ui.control.NumericEditField
        OutsideTemperatureCEditFieldLabel  matlab.ui.control.Label
        OutsideTemperatureCEditField    matlab.ui.control.NumericEditField
        RelativeHumidityEditFieldLabel  matlab.ui.control.Label
        RelativeHumidityEditField       matlab.ui.control.NumericEditField
        DateLabelNoEdit_1               matlab.ui.control.Label
        TimeLabelNoEdit_1               matlab.ui.control.Label
        DateLabel_1                     matlab.ui.control.Label
        TimeLabel_1                     matlab.ui.control.Label
        CropAreaLabel                   matlab.ui.control.Label
        CropAreaLabel_NoEdit            matlab.ui.control.Label
        TotalAreaLabel                  matlab.ui.control.Label
        TotalAreaLabel_NoEdit           matlab.ui.control.Label
        WaterTreatmentAreaLabel_NoEdit  matlab.ui.control.Label
        WaterTreatmentAreaLabel         matlab.ui.control.Label
        LivingAreaLabel_NoEdit          matlab.ui.control.Label
        LivingAreaLabel                 matlab.ui.control.Label
        WaterStorageAreaLabel_NoEdit    matlab.ui.control.Label
        WaterStorageAreaLabel           matlab.ui.control.Label
        TemperatureTab                  matlab.ui.container.Tab
        UITemperatureAxes               matlab.ui.control.UIAxes
        StepButton1h                    matlab.ui.control.Button
        StepButton1d                    matlab.ui.control.Button
        StepButton1w                    matlab.ui.control.Button
        DateLabelNoEdit_2               matlab.ui.control.Label
        TimeLabelNoEdit_2               matlab.ui.control.Label
        DateLabel_2                     matlab.ui.control.Label
        TimeLabel_2                     matlab.ui.control.Label
        CropTab                         matlab.ui.container.Tab
        UICropBiomassAxes               matlab.ui.control.UIAxes
        StepButton2w                    matlab.ui.control.Button
        StepButton2d                    matlab.ui.control.Button
        StepButton2h                    matlab.ui.control.Button
        UICropSupplyDemandAxes          matlab.ui.control.UIAxes
        CropLabelNoEdit                 matlab.ui.control.Label
        CropLabel                       matlab.ui.control.Label
        CurrentSupplyLabelNoEdit        matlab.ui.control.Label
        CurrentDemandLabelNoEdit        matlab.ui.control.Label
        CurrentDemandLabel              matlab.ui.control.Label
        CurrentSupplyLabel              matlab.ui.control.Label
        CurrentSupplyLabelNoEdit_2      matlab.ui.control.Label
        CurrentDemandLabelNoEdit_2      matlab.ui.control.Label
        DateLabelNoEdit_3               matlab.ui.control.Label
        TimeLabelNoEdit_3               matlab.ui.control.Label
        DateLabel_3                     matlab.ui.control.Label
        TimeLabel_3                     matlab.ui.control.Label
        WaterTab                        matlab.ui.container.Tab
        UIWaterAxes                     matlab.ui.control.UIAxes
        StepButton3w                    matlab.ui.control.Button
        StepButton3d                    matlab.ui.control.Button
        StepButton3h                    matlab.ui.control.Button
        DateLabelNoEdit_4               matlab.ui.control.Label
        TimeLabelNoEdit_4               matlab.ui.control.Label
        DateLabel_4                     matlab.ui.control.Label
        TimeLabel_4                     matlab.ui.control.Label
        FlowTab                         matlab.ui.container.Tab
        UIWaterFlowAxes                 matlab.ui.control.UIAxes
        StepButton4w                    matlab.ui.control.Button
        StepButton4d                    matlab.ui.control.Button
        StepButton4h                    matlab.ui.control.Button
        DateLabelNoEdit_5               matlab.ui.control.Label
        TimeLabelNoEdit_5               matlab.ui.control.Label
        DateLabel_5                     matlab.ui.control.Label
        TimeLabel_5                     matlab.ui.control.Label
        TreatmentTab                    matlab.ui.container.Tab
        UITreatmentAxes                 matlab.ui.control.UIAxes
        DateLabelNoEdit_7               matlab.ui.control.Label
        TimeLabelNoEdit_7               matlab.ui.control.Label
        DateLabel_7                     matlab.ui.control.Label
        TimeLabel_7                     matlab.ui.control.Label
        StepButton6h                    matlab.ui.control.Button
        StepButton6d                    matlab.ui.control.Button
        StepButton6w                    matlab.ui.control.Button
        EnergyTab                       matlab.ui.container.Tab
        DateLabelNoEdit_6               matlab.ui.control.Label
        TimeLabelNoEdit_6               matlab.ui.control.Label
        DateLabel_6                     matlab.ui.control.Label
        TimeLabel_6                     matlab.ui.control.Label
        UIEnergyAxes                    matlab.ui.control.UIAxes
        StepButton5w                    matlab.ui.control.Button
        StepButton5d                    matlab.ui.control.Button
        StepButton5h                    matlab.ui.control.Button
        TotalEnergyUsageLabelNoEdit     matlab.ui.control.Label
        TotalEnergyUsageLabel           matlab.ui.control.Label
    end

    
    properties (Access = private)
        m                               % Model handle
    end
    
    methods (Access = private)
        
        function PlotVars(app)
            if isempty(app.m.hours)
                return
            end
            
            
            days = app.m.hours/24;
            sIndex = 1;
            
            if max(days) > 100
                % Scroll the days so only last 100 days show
                currentHour = max(app.m.hours);
                hundredDays = 100 * 24;
                sIndex = currentHour - hundredDays;
                days = days(sIndex: end);
            end
            
            lim = [min(days) max(days)];
            if length(days) == 1
                lim = [0 max(days)];
            end
            
            % Collect variables for plotting
            % Temperature variables
            Ta = app.m.trackTa(sIndex:end);
            Tw = app.m.trackTw(sIndex:end);
            
            plot(app.UITemperatureAxes, days, Ta, days, Tw);
            legend(app.UITemperatureAxes, ["Air", "Water"]);
            xlim(app.UITemperatureAxes, lim);
            
            % Crop variables
            trackLeafB=app.m.trackLeafB(sIndex:end)/1000;
            trackStorB=app.m.trackStorB(sIndex:end)/1000;
            trackStemB=app.m.trackStemB(sIndex:end)/1000;
            trackRootB=app.m.trackRootB(sIndex:end)/1000;
          
            plot(app.UICropBiomassAxes, days, trackLeafB, days, trackStemB, days,...
                trackRootB, days, trackStorB);
            legend(app.UICropBiomassAxes,["Leaf", "Stem", "Roots", "Grain"], 'Location', 'northwest');
            xlim(app.UICropBiomassAxes, lim);
            
             % Setup ylim since values dip slightly  below 0 due to rounding errors
             ymin = 0;
             ymax = max([max(trackLeafB) max(trackStorB) max(trackStemB) max(trackRootB)]);
                
            if ymax > 0
                ylim(app.UICropBiomassAxes, [ymin ymax]);
            end
            
            % Crop supply/demand variables
            currentDemand = ones(size(app.m.hours))*app.m.currentHourlyDemand;
            currentDemand = currentDemand(sIndex:end);
            currentSupply = app.m.currentSupply(sIndex:end);
            
            plot(app.UICropSupplyDemandAxes, days, currentDemand, days, currentSupply);
            legend(app.UICropSupplyDemandAxes, ["Demand", "Supply"], 'Location', 'northwest');
            xlim(app.UICropSupplyDemandAxes, lim);
            
            % Water variables
            trackVcrop      =app.m.trackVcrop(sIndex:end);
            trackVwetland   =app.m.trackVwetland(sIndex:end);
            trackVvapor     =app.m.trackVvapor(sIndex:end);
            trackVstore     =app.m.trackVstore(sIndex:end);
            trackVsed       =app.m.trackVsed(sIndex:end);
            trackVreject    =app.m.trackVreject(sIndex:end);
            
            semilogy(app.UIWaterAxes, days, trackVstore, days, trackVvapor, days,...
                trackVcrop, days, trackVsed, days, trackVwetland, days, trackVreject);
            legend(app.UIWaterAxes,["Storage","Air","Crop","Sed. Basin","Wetland","Reject"]);
            xlim(app.UIWaterAxes, lim);
            
            % Energy demand variables
            % Lighting
            gLight = app.m.trackgLight(sIndex:end);
            lLight = app.m.tracklLight(sIndex:end);
            
            % Treatment
            RO      = app.m.trackRO(sIndex:end);
            UV      = app.m.trackUV(sIndex:end);
            
            % Water reject evaporation
            RejectEvap = app.m.trackRejectEvap(sIndex:end);
            
            semilogy(app.UIEnergyAxes, days, gLight, days, lLight, days, RO, days, UV, days, RejectEvap);
            legend(app.UIEnergyAxes, ["Crop Lights", "Living Lights", "RO", "UV", "Reject Evap."]);
            xlim(app.UIEnergyAxes, lim);
        
            % Water quality variables
            TSS = app.m.trackTSS;
            sampling_locs = {"Wastewater", "Post-Sedimentation", "Post-Wetland"};
            plot(app.UITreatmentAxes, TSS);
            legend(app.UITreatmentAxes,"TSS");
            set(app.UITreatmentAxes,'XTick',1:3,'XTickLabel',sampling_locs);
        end
    
        function SetupCropLabels(app)
             % Get crop parameters
             type = string(app.m.cropBlock.type);
             currentDemand = sprintf("%0.2f",app.m.currentHourlyDemand*24);
             currentSupply = sprintf("%0.2f",crop.determineDailyCropDemand(app.m.no_people, app.m.cropBlock.type)*90*1.1);
             
             % Set labels in console
             app.CropLabel.Text = type;
             app.CurrentDemandLabel.Text = currentDemand;
             app.CurrentSupplyLabel.Text = currentSupply;
        end
    
        function UpdateCropLabels(app)
            % Get crop parameters
            currentDemand = sprintf("%0.2f",app.m.currentHourlyDemand*24);
            currentSupply = sprintf("%0.2f",app.m.currentSupply(end));
             
            % Set labels in console
             app.CurrentDemandLabel.Text = currentDemand;
             app.CurrentSupplyLabel.Text = currentSupply;
        end
    
        function PlotWaterFlows(app)
             % Collect water flow variables
            ET = app.m.W.ET;
            WW = app.m.W.WW;
            evap = app.m.W.evap; 
            cond = app.m.W.cond;
            VRO = app.m.W.VRO;
%             ROreject = app.m.W.ROreject;
%             dVwetland = app.m.W.dVwetland;

            % Use alluvialflow function to plot flows throughout water system
            if ET == 0
                if evap == 0 || cond == 0
                    left_labels = {'Storage', 'Air', 'Treatment'};
                    right_labels = {'Storage', 'Air', 'Treatment'};
                    
                    % ROW is for each left_label, COLUMN is for each right_label
                    % i.e. flow_data(1,2) is flow from left_label{1} to right_label{2}
                    flow_data = [0 evap max(WW, VRO);
                                 cond 0 0;
                                 VRO 0 0];
                else
                    left_labels = {'Storage', 'Treatment'};
                    right_labels = {'Storage', 'Treatment'};
                    
                    % ROW is for each left_label, COLUMN is for each right_label
                    % i.e. flow_data(1,2) is flow from left_label{1} to right_label{2}
                    flow_data = [0 max(WW, VRO);
                                 VRO 0];
                end
            else
                left_labels = {'Storage', 'Air', 'Crop', 'Treatment'};
                right_labels = {'Storage', 'Air', 'Crop', 'Treatment'};
                
                % ROW is for each left_label, COLUMN is for each right_label
                % i.e. flow_data(1,2) is flow from left_label{1} to right_label{2}
                flow_data = [0 evap ET max(WW, VRO);
                             cond 0 0 0;
                             0 ET 0 0;
                             VRO 0 0 0];
            end
            
            cla(app.UIWaterFlowAxes);
            alluvialflow(flow_data, left_labels, right_labels, "Water Flows", app.UIWaterFlowAxes);
        end

        function Step(app, hours)
            for i = 1:hours
                app.m.step;
            end
            app.PlotVars;
            app.PlotWaterFlows;
            app.UpdateCropLabels;
            app.UpdateDateTime;
            app.UpdateEnergyUsageLabel;
            app.UpdateBackgroundColor;
        end
        
        function UpdateDateTime(app)
            date = string(datetime(app.m.date, 'Format', 'MMM dd, yyyy'));
            time = string(datetime(app.m.date, 'Format', 'HH:mm'));
            
            app.DateLabel_1.Text = date;
            app.DateLabel_2.Text = date;
            app.DateLabel_3.Text = date;
            app.DateLabel_4.Text = date;
            app.DateLabel_5.Text = date;
            app.DateLabel_6.Text = date;
            app.DateLabel_7.Text = date;
            
            app.TimeLabel_1.Text = time;
            app.TimeLabel_2.Text = time;
            app.TimeLabel_3.Text = time;
            app.TimeLabel_4.Text = time;
            app.TimeLabel_5.Text = time;
            app.TimeLabel_6.Text = time;
            app.TimeLabel_7.Text = time;
        end
        
        function UpdateEnergyUsageLabel(app)
            % Lighting
            gLight = app.m.gLight.EnergyUsage;
            lLight = app.m.lLight.EnergyUsage;
            
            % Treatment
            RO     = filterUnit.RO_P;
            UV     = filterUnit.UV_P;
            
            % Reject evaporation
            RejectEvap = refEQ.L(app.m.waterBlock.Tw_C)*app.m.W.Mreject/3600;
            
            totalEnergy = gLight + lLight + RO + UV + RejectEvap;
            
            if totalEnergy > 1000
                totalEnergy = totalEnergy/1000;
                label = " MW";
            else
                label = " kW";
            end
            
            app.TotalEnergyUsageLabel.Text = sprintf("%0.2f"+label,totalEnergy);
        end
        
        function UpdateBackgroundColor(app)
            % Update background color depending on the time of day
            hour = app.m.hour;
            onT  = app.m.lLight.onT;
            offT = app.m.lLight.offT;
            if hour >= onT && hour <= offT
                % Lights are on
                onColor = [0.94 0.94 0.94];
%                 set(app.UIEnergyAxes, 'Color', onColor);
                app.DetailsTab.BackgroundColor = onColor;
                app.TemperatureTab.BackgroundColor = onColor;
                app.CropTab.BackgroundColor = onColor;
                app.WaterTab.BackgroundColor = onColor;
                app.FlowTab.BackgroundColor = onColor;
                app.EnergyTab.BackgroundColor = onColor;
                app.TreatmentTab.BackgroundColor = onColor;
            else
                if hour == 0 || hour == 24 || hour == 5
                    offColor = [0.9 0.9 0.9];
                elseif hour == 1 || hour == 4
                    offColor = [0.8 0.8 0.8];
                elseif hour == 2 || hour == 3
                    offColor = [0.64 0.64 0.64];
                end
%                 set(app.UIEnergyAxes, 'Color', offColor);
                app.DetailsTab.BackgroundColor = offColor;
                app.TemperatureTab.BackgroundColor = offColor;
                app.CropTab.BackgroundColor = offColor;
                app.WaterTab.BackgroundColor = offColor;
                app.FlowTab.BackgroundColor = offColor;
                app.EnergyTab.BackgroundColor = offColor;
                app.TreatmentTab.BackgroundColor = offColor;
            end
        end
        
        
        function SetupAreaLabels(app)
            [At, Ac, Aw, Al] = model.determineArea(app.m.no_people);
            Atotal = At + Ac + Aw + Al;
            
            livingArea = sprintf("%0.2f m^2",Al);
            treatmentArea = sprintf("%0.2f m^2",At);
            waterArea = sprintf("%0.2f m^2",Aw);
            cropArea = sprintf("%0.2f m^2",Ac);
            totalArea = sprintf("%0.2f m^2",Atotal);
            
            app.TotalAreaLabel.Text = totalArea;
            app.CropAreaLabel.Text = cropArea;
            app.WaterStorageAreaLabel.Text = waterArea;
            app.WaterTreatmentAreaLabel.Text = treatmentArea;
            app.LivingAreaLabel.Text = livingArea;
            
            app.TotalAreaLabel.HorizontalAlignment = 'right';
            app.CropAreaLabel.HorizontalAlignment = 'right';
            app.WaterStorageAreaLabel.HorizontalAlignment = 'right';
            app.WaterTreatmentAreaLabel.HorizontalAlignment = 'right';
            app.LivingAreaLabel.HorizontalAlignment = 'right';
        end
    end
    

    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % Disable Start button to prevent action being called twice.
            app.StartButton.Enable = 'off';
            app.NumberofPeopleEditField.Enable = 'off';
            app.AirTemperatureCEditField.Enable = 'off';
            app.InitialPressurekPaEditField.Enable = 'off';
            app.OutsideTemperatureCEditField.Enable = 'off';
            app.RelativeHumidityEditField.Enable = 'off';
            
            % Initialize model paths
            addpath blocks;
            addpath data;
            addpath eqn;
            addpath alluvialflow;
            
            % Initialize variables from app
            no_people = app.NumberofPeopleEditField.Value;
            P = app.InitialPressurekPaEditField.Value;
            Ta = app.AirTemperatureCEditField.Value;
            To = app.OutsideTemperatureCEditField.Value;
            RH = app.RelativeHumidityEditField.Value/100;
            dt = time.HOUR;
            
            % Create the model
            app.m = model(no_people, P, Ta, To, RH, dt);
            
            % Initialize Play/Step buttons
            app.StepButton1h.Enable = 'on';
            app.StepButton1d.Enable = 'on';
            app.StepButton1w.Enable = 'on';
            
            app.StepButton2h.Enable = 'on';
            app.StepButton2d.Enable = 'on';
            app.StepButton2w.Enable = 'on';
            
            app.StepButton3h.Enable = 'on';
            app.StepButton3d.Enable = 'on';
            app.StepButton3w.Enable = 'on';
            
            app.StepButton4h.Enable = 'on';
            app.StepButton4d.Enable = 'on';
            app.StepButton4w.Enable = 'on';
            
            app.StepButton5h.Enable = 'on';
            app.StepButton5d.Enable = 'on';
            app.StepButton5w.Enable = 'on';
            
            app.StepButton6h.Enable = 'on';
            app.StepButton6d.Enable = 'on';
            app.StepButton6w.Enable = 'on';
            
            app.SetupAreaLabels;
            app.SetupCropLabels;
            app.UpdateDateTime;
        end

        % Button pushed function: StepButton1h, StepButton2h, 
        % StepButton3h, StepButton4h, StepButton5h, StepButton6h
        function StepHourButtonPushed(app, event)
            app.Step(1);
        end

        % Button pushed function: StepButton1d, StepButton2d, 
        % StepButton3d, StepButton4d, StepButton5d, StepButton6d
        function StepDayButtonPushed(app, event)
            app.Step(24);
        end

        % Button pushed function: StepButton1w, StepButton2w, 
        % StepButton3w, StepButton4w, StepButton5w, StepButton6w
        function StepWeekButtonPushed(app, event)
            app.Step(168);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DetailsTab = uitab(app.TabGroup);
            app.DetailsTab.Title = 'Details';
            app.BioSimLabel = uilabel(app.DetailsTab);
            app.BioSimLabel.Text = 'BioSim';
            app.NumberofPeopleEditFieldLabel = uilabel(app.DetailsTab);
            app.NumberofPeopleEditFieldLabel.Text = 'Number of People';
            app.NumberofPeopleEditField = uieditfield(app.DetailsTab, 'numeric');
            app.NumberofPeopleEditField.Limits = [1 50];
            app.NumberofPeopleEditField.Value = 8;
            app.StartButton = uibutton(app.DetailsTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.InitialPressurekPaEditFieldLabel = uilabel(app.DetailsTab);
            app.InitialPressurekPaEditFieldLabel.Text = 'Initial Pressure (kPa)';
            app.InitialPressurekPaEditField = uieditfield(app.DetailsTab, 'numeric');
            app.InitialPressurekPaEditField.Limits = [20 120];
            app.InitialPressurekPaEditField.Value = 101.325;
            app.AirTemperatureCEditFieldLabel = uilabel(app.DetailsTab);
            app.AirTemperatureCEditFieldLabel.Text = 'Air Temperature (C)';
            app.AirTemperatureCEditField = uieditfield(app.DetailsTab, 'numeric');
            app.AirTemperatureCEditField.Limits = [0 40];
            app.AirTemperatureCEditField.Value = 20;
            app.OutsideTemperatureCEditFieldLabel = uilabel(app.DetailsTab);
            app.OutsideTemperatureCEditFieldLabel.Text = 'Outside Temperature (C)';
            app.OutsideTemperatureCEditField = uieditfield(app.DetailsTab, 'numeric');
            app.OutsideTemperatureCEditField.Limits = [0 40];
            app.OutsideTemperatureCEditField.Value = 20;
            app.RelativeHumidityEditFieldLabel = uilabel(app.DetailsTab);
            app.RelativeHumidityEditFieldLabel.Text = 'Relative Humidity (%)';
            app.RelativeHumidityEditField = uieditfield(app.DetailsTab, 'numeric');
            app.RelativeHumidityEditField.Limits = [0 100];
            app.RelativeHumidityEditField.Value = 50;
            app.DateLabelNoEdit_1 = uilabel(app.DetailsTab);
            app.DateLabelNoEdit_1.Text = 'Date:';
            app.TimeLabelNoEdit_1 = uilabel(app.DetailsTab);
            app.TimeLabelNoEdit_1.Text = 'Time:';
            app.DateLabel_1 = uilabel(app.DetailsTab);
            app.DateLabel_1.Text = 'N/A';
            app.TimeLabel_1 = uilabel(app.DetailsTab);
            app.TimeLabel_1.Text = 'N/A';
            app.CropAreaLabel = uilabel(app.DetailsTab);
            app.CropAreaLabel.Text = 'N/A';
            app.CropAreaLabel_NoEdit = uilabel(app.DetailsTab);
            app.CropAreaLabel_NoEdit.Text = 'Crop:';
            app.TotalAreaLabel = uilabel(app.DetailsTab);
            app.TotalAreaLabel.Text = 'N/A';
            app.TotalAreaLabel_NoEdit = uilabel(app.DetailsTab);
            app.TotalAreaLabel_NoEdit.Text = 'Total:';
            app.WaterTreatmentAreaLabel_NoEdit = uilabel(app.DetailsTab);
            app.WaterTreatmentAreaLabel_NoEdit.Text = {'Water '; 'Treatment:'};
            app.WaterTreatmentAreaLabel = uilabel(app.DetailsTab);
            app.WaterTreatmentAreaLabel.Text = 'N/A';
            app.LivingAreaLabel_NoEdit = uilabel(app.DetailsTab);
            app.LivingAreaLabel_NoEdit.Text = 'Living:';
            app.LivingAreaLabel = uilabel(app.DetailsTab);
            app.LivingAreaLabel.Text = 'N/A';
            app.WaterStorageAreaLabel_NoEdit = uilabel(app.DetailsTab);
            app.WaterStorageAreaLabel_NoEdit.Text = {'Water '; 'Storage:'};
            app.WaterStorageAreaLabel = uilabel(app.DetailsTab);
            app.WaterStorageAreaLabel.Text = 'N/A';
            app.TemperatureTab = uitab(app.TabGroup);
            app.TemperatureTab.Title = 'Temperature';
            app.UITemperatureAxes = uiaxes(app.TemperatureTab);
            title(app.UITemperatureAxes, 'Temperature Gauge')
            xlabel(app.UITemperatureAxes, 'Day')
            ylabel(app.UITemperatureAxes, 'Temperature (C)')
            app.StepButton1h = uibutton(app.TemperatureTab, 'push');
            app.StepButton1h.ButtonPushedFcn = createCallbackFcn(app, @StepHourButtonPushed, true);
            app.StepButton1h.Enable = 'off';
            app.StepButton1h.Text = {'>'; '1 hr'};
            app.StepButton1d = uibutton(app.TemperatureTab, 'push');
            app.StepButton1d.ButtonPushedFcn = createCallbackFcn(app, @StepDayButtonPushed, true);
            app.StepButton1d.Enable = 'off';
            app.StepButton1d.Text = {'>'; '1 d'};
            app.StepButton1w = uibutton(app.TemperatureTab, 'push');
            app.StepButton1w.ButtonPushedFcn = createCallbackFcn(app, @StepWeekButtonPushed, true);
            app.StepButton1w.Enable = 'off';
            app.StepButton1w.Text = {'>'; '1 w'};
            app.DateLabelNoEdit_2 = uilabel(app.TemperatureTab);
            app.DateLabelNoEdit_2.Text = 'Date:';
            app.TimeLabelNoEdit_2 = uilabel(app.TemperatureTab);
            app.TimeLabelNoEdit_2.Text = 'Time:';
            app.DateLabel_2 = uilabel(app.TemperatureTab);
            app.DateLabel_2.Text = 'N/A';
            app.TimeLabel_2 = uilabel(app.TemperatureTab);
            app.TimeLabel_2.Text = 'N/A';
            app.CropTab = uitab(app.TabGroup);
            app.CropTab.Title = 'Crop';
            app.UICropBiomassAxes = uiaxes(app.CropTab);
            xlabel(app.UICropBiomassAxes, 'Day')
            ylabel(app.UICropBiomassAxes, 'Biomass (kg)')
            app.StepButton2w = uibutton(app.CropTab, 'push');
            app.StepButton2w.ButtonPushedFcn = createCallbackFcn(app, @StepWeekButtonPushed, true);
            app.StepButton2w.Enable = 'off';
            app.StepButton2w.Text = {'>'; '1 w'};
            app.StepButton2d = uibutton(app.CropTab, 'push');
            app.StepButton2d.ButtonPushedFcn = createCallbackFcn(app, @StepDayButtonPushed, true);
            app.StepButton2d.Enable = 'off';
            app.StepButton2d.Text = {'>'; '1 d'};
            app.StepButton2h = uibutton(app.CropTab, 'push');
            app.StepButton2h.ButtonPushedFcn = createCallbackFcn(app, @StepHourButtonPushed, true);
            app.StepButton2h.Enable = 'off';
            app.StepButton2h.Text = {'>'; '1 hr'};
            app.UICropSupplyDemandAxes = uiaxes(app.CropTab);
            title(app.UICropSupplyDemandAxes, 'Supply/Demand')
            xlabel(app.UICropSupplyDemandAxes, 'Day')
            ylabel(app.UICropSupplyDemandAxes, 'Biomass (kg)')
            app.CropLabelNoEdit = uilabel(app.CropTab);
            app.CropLabelNoEdit.Text = 'Crop:';
            app.CropLabel = uilabel(app.CropTab);
            app.CropLabel.Text = 'N/A';
            app.CurrentSupplyLabelNoEdit = uilabel(app.CropTab);
            app.CurrentSupplyLabelNoEdit.Text = 'Current Supply:';
            app.CurrentDemandLabelNoEdit = uilabel(app.CropTab);
            app.CurrentDemandLabelNoEdit.Text = 'Current Demand:';
            app.CurrentDemandLabel = uilabel(app.CropTab);
            app.CurrentDemandLabel.Text = 'N/A';
            app.CurrentSupplyLabel = uilabel(app.CropTab);
            app.CurrentSupplyLabel.Text = 'N/A';
            app.CurrentSupplyLabelNoEdit_2 = uilabel(app.CropTab);
            app.CurrentSupplyLabelNoEdit_2.Text = 'kg';
            app.CurrentDemandLabelNoEdit_2 = uilabel(app.CropTab);
            app.CurrentDemandLabelNoEdit_2.Text = 'kg/day';
            app.DateLabelNoEdit_3 = uilabel(app.CropTab);
            app.DateLabelNoEdit_3.Text = 'Date:';
            app.TimeLabelNoEdit_3 = uilabel(app.CropTab);
            app.TimeLabelNoEdit_3.Text = 'Time:';
            app.DateLabel_3 = uilabel(app.CropTab);
            app.DateLabel_3.Text = 'N/A';
            app.TimeLabel_3 = uilabel(app.CropTab);
            app.TimeLabel_3.Text = 'N/A';
            app.WaterTab = uitab(app.TabGroup);
            app.WaterTab.Title = 'Water';
            app.UIWaterAxes = uiaxes(app.WaterTab);
            title(app.UIWaterAxes, 'Water Gauge')
            xlabel(app.UIWaterAxes, 'Day')
            ylabel(app.UIWaterAxes, 'Volume (m^3)')
            app.StepButton3w = uibutton(app.WaterTab, 'push');
            app.StepButton3w.ButtonPushedFcn = createCallbackFcn(app, @StepWeekButtonPushed, true);
            app.StepButton3w.Enable = 'off';
            app.StepButton3w.Text = {'>'; '1 w'};
            app.StepButton3d = uibutton(app.WaterTab, 'push');
            app.StepButton3d.ButtonPushedFcn = createCallbackFcn(app, @StepDayButtonPushed, true);
            app.StepButton3d.Enable = 'off';
            app.StepButton3d.Text = {'>'; '1 d'};
            app.StepButton3h = uibutton(app.WaterTab, 'push');
            app.StepButton3h.ButtonPushedFcn = createCallbackFcn(app, @StepHourButtonPushed, true);
            app.StepButton3h.Enable = 'off';
            app.StepButton3h.Text = {'>'; '1 hr'};
            app.DateLabelNoEdit_4 = uilabel(app.WaterTab);
            app.DateLabelNoEdit_4.Text = 'Date:';
            app.TimeLabelNoEdit_4 = uilabel(app.WaterTab);
            app.TimeLabelNoEdit_4.Text = 'Time:';
            app.DateLabel_4 = uilabel(app.WaterTab);
            app.DateLabel_4.Text = 'N/A';
            app.TimeLabel_4 = uilabel(app.WaterTab);
            app.TimeLabel_4.Text = 'N/A';
            app.FlowTab = uitab(app.TabGroup);
            app.FlowTab.Title = 'Flow';
            app.UIWaterFlowAxes = uiaxes(app.FlowTab);
            app.StepButton4w = uibutton(app.FlowTab, 'push');
            app.StepButton4w.ButtonPushedFcn = createCallbackFcn(app, @StepWeekButtonPushed, true);
            app.StepButton4w.Enable = 'off';
            app.StepButton4w.Text = {'>'; '1 w'};
            app.StepButton4d = uibutton(app.FlowTab, 'push');
            app.StepButton4d.ButtonPushedFcn = createCallbackFcn(app, @StepDayButtonPushed, true);
            app.StepButton4d.Enable = 'off';
            app.StepButton4d.Text = {'>'; '1 d'};
            app.StepButton4h = uibutton(app.FlowTab, 'push');
            app.StepButton4h.ButtonPushedFcn = createCallbackFcn(app, @StepHourButtonPushed, true);
            app.StepButton4h.Enable = 'off';
            app.StepButton4h.Text = {'>'; '1 hr'};
            app.DateLabelNoEdit_5 = uilabel(app.FlowTab);
            app.DateLabelNoEdit_5.Text = 'Date:';
            app.TimeLabelNoEdit_5 = uilabel(app.FlowTab);
            app.TimeLabelNoEdit_5.Text = 'Time:';
            app.DateLabel_5 = uilabel(app.FlowTab);
            app.DateLabel_5.Text = 'N/A';
            app.TimeLabel_5 = uilabel(app.FlowTab);
            app.TimeLabel_5.Text = 'N/A';
            app.TreatmentTab = uitab(app.TabGroup);
            app.TreatmentTab.Title = 'Treatment';
            app.UITreatmentAxes = uiaxes(app.TreatmentTab);
            title(app.UITreatmentAxes, 'Water Quality Gauge')
            ylabel(app.UITreatmentAxes, 'Concentration (mg/L)')
            app.DateLabelNoEdit_7 = uilabel(app.TreatmentTab);
            app.DateLabelNoEdit_7.Text = 'Date:';
            app.TimeLabelNoEdit_7 = uilabel(app.TreatmentTab);
            app.TimeLabelNoEdit_7.Text = 'Time:';
            app.DateLabel_7 = uilabel(app.TreatmentTab);
            app.DateLabel_7.Text = 'N/A';
            app.TimeLabel_7 = uilabel(app.TreatmentTab);
            app.TimeLabel_7.Text = 'N/A';
            app.StepButton6h = uibutton(app.TreatmentTab, 'push');
            app.StepButton6h.ButtonPushedFcn = createCallbackFcn(app, @StepHourButtonPushed, true);
            app.StepButton6h.Enable = 'off';
            app.StepButton6h.Text = {'>'; '1 hr'};
            app.StepButton6d = uibutton(app.TreatmentTab, 'push');
            app.StepButton6d.ButtonPushedFcn = createCallbackFcn(app, @StepDayButtonPushed, true);
            app.StepButton6d.Enable = 'off';
            app.StepButton6d.Text = {'>'; '1 d'};
            app.StepButton6w = uibutton(app.TreatmentTab, 'push');
            app.StepButton6w.ButtonPushedFcn = createCallbackFcn(app, @StepWeekButtonPushed, true);
            app.StepButton6w.Enable = 'off';
            app.StepButton6w.Text = {'>'; '1 w'};
            app.EnergyTab = uitab(app.TabGroup);
            app.EnergyTab.Title = 'Energy';
            app.DateLabelNoEdit_6 = uilabel(app.EnergyTab);
            app.DateLabelNoEdit_6.Text = 'Date:';
            app.TimeLabelNoEdit_6 = uilabel(app.EnergyTab);
            app.TimeLabelNoEdit_6.Text = 'Time:';
            app.DateLabel_6 = uilabel(app.EnergyTab);
            app.DateLabel_6.Text = 'N/A';
            app.TimeLabel_6 = uilabel(app.EnergyTab);
            app.TimeLabel_6.Text = 'N/A';
            app.UIEnergyAxes = uiaxes(app.EnergyTab);
            title(app.UIEnergyAxes, 'Energy Usage')
            xlabel(app.UIEnergyAxes, 'Day')
            ylabel(app.UIEnergyAxes, 'Energy (kW)')
            app.StepButton5w = uibutton(app.EnergyTab, 'push');
            app.StepButton5w.ButtonPushedFcn = createCallbackFcn(app, @StepWeekButtonPushed, true);
            app.StepButton5w.Enable = 'off';
            app.StepButton5w.Text = {'>'; '1 w'};
            app.StepButton5d = uibutton(app.EnergyTab, 'push');
            app.StepButton5d.ButtonPushedFcn = createCallbackFcn(app, @StepDayButtonPushed, true);
            app.StepButton5d.Enable = 'off';
            app.StepButton5d.Text = {'>'; '1 d'};
            app.StepButton5h = uibutton(app.EnergyTab, 'push');
            app.StepButton5h.ButtonPushedFcn = createCallbackFcn(app, @StepHourButtonPushed, true);
            app.StepButton5h.Enable = 'off';
            app.StepButton5h.Text = {'>'; '1 hr'};
            app.TotalEnergyUsageLabelNoEdit = uilabel(app.EnergyTab);
            app.TotalEnergyUsageLabelNoEdit.Text = 'Total Energy Usage:';
            app.TotalEnergyUsageLabel = uilabel(app.EnergyTab);
            app.TotalEnergyUsageLabel.Text = 'N/A';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end